classdef DrRobotsMatlabSDKOdmetryRecord_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        uipanel5               matlab.ui.container.Panel
        textSeq                matlab.ui.control.Label
        text66                 matlab.ui.control.Label
        textYaw                matlab.ui.control.Label
        text64                 matlab.ui.control.Label
        textGPSState           matlab.ui.control.Label
        textGPSVOG             matlab.ui.control.Label
        textGPSCOG             matlab.ui.control.Label
        textGPSLong            matlab.ui.control.Label
        textGPSLat             matlab.ui.control.Label
        textCompZ              matlab.ui.control.Label
        textCompY              matlab.ui.control.Label
        textCompX              matlab.ui.control.Label
        textGyroZ              matlab.ui.control.Label
        textGyroY              matlab.ui.control.Label
        textGyroX              matlab.ui.control.Label
        textAccelZ             matlab.ui.control.Label
        textAccelY             matlab.ui.control.Label
        textAccelX             matlab.ui.control.Label
        text49                 matlab.ui.control.Label
        text48                 matlab.ui.control.Label
        text47                 matlab.ui.control.Label
        text46                 matlab.ui.control.Label
        text45                 matlab.ui.control.Label
        text44                 matlab.ui.control.Label
        text43                 matlab.ui.control.Label
        text42                 matlab.ui.control.Label
        text41                 matlab.ui.control.Label
        text40                 matlab.ui.control.Label
        text39                 matlab.ui.control.Label
        uipanel4               matlab.ui.container.Panel
        text38                 matlab.ui.control.Label
        textBatteryVol         matlab.ui.control.Label
        textDriver2State       matlab.ui.control.Label
        text35                 matlab.ui.control.Label
        textDriver1State       matlab.ui.control.Label
        text33                 matlab.ui.control.Label
        uipanel3               matlab.ui.container.Panel
        pushbuttonSimulate     matlab.ui.control.Button
        pushbuttonSensorCal    matlab.ui.control.Button
        pushbuttonOff          matlab.ui.control.Button
        pushbuttonOn           matlab.ui.control.Button
        EStop                  matlab.ui.control.Button
        sliderPWMPower         matlab.ui.control.Slider
        pushbuttonGoBack       matlab.ui.control.Button
        pushbuttonTurnRight    matlab.ui.control.Button
        pushbuttonTurnLeft     matlab.ui.control.Button
        pushbuttonStop         matlab.ui.control.Button
        pushbuttonGoF          matlab.ui.control.Button
        uipanel2               matlab.ui.container.Panel
        textRightFrontPos      matlab.ui.control.Label
        textRightRearCurrent   matlab.ui.control.Label
        textLeftRearCurrent    matlab.ui.control.Label
        textRightFrontCurrent  matlab.ui.control.Label
        textLeftFrontCurrent   matlab.ui.control.Label
        text28                 matlab.ui.control.Label
        textRightRearTemp      matlab.ui.control.Label
        textLeftRearTemp       matlab.ui.control.Label
        textRightFrontTemp     matlab.ui.control.Label
        textRightRearVel       matlab.ui.control.Label
        textLeftRearVel        matlab.ui.control.Label
        textRightRearPos       matlab.ui.control.Label
        textLeftRearPos        matlab.ui.control.Label
        textRightRearPWM       matlab.ui.control.Label
        textLeftRearPWM        matlab.ui.control.Label
        textLeftFrontTemp      matlab.ui.control.Label
        text17                 matlab.ui.control.Label
        text16                 matlab.ui.control.Label
        textRightFrontVel      matlab.ui.control.Label
        text14                 matlab.ui.control.Label
        textLeftFrontVel       matlab.ui.control.Label
        text12                 matlab.ui.control.Label
        text10                 matlab.ui.control.Label
        textLeftFrontPos       matlab.ui.control.Label
        text8                  matlab.ui.control.Label
        textRightFrontPWM      matlab.ui.control.Label
        text6                  matlab.ui.control.Label
        textLeftFrontPWM       matlab.ui.control.Label
        text4                  matlab.ui.control.Label
        uipanel1               matlab.ui.container.Panel
        editRobotPort          matlab.ui.control.EditField
        text3                  matlab.ui.control.Label
        text2                  matlab.ui.control.Label
        editRobotIP            matlab.ui.control.EditField
        pushbuttonConnect      matlab.ui.control.Button
    end

    
    methods (Access = private)
        function tempM = AD2Temperature(app, adValue)
                resTable = [114660.0,84510,62927,47077,35563,27119,20860,16204,12683,10000,7942,6327,5074,4103,3336,2724,2237,1846,1530,1275,1068,899.3,760.7,645.2,549.4];
                tempTable = [-20, -15, -10, -5, 0, 5, 10, 15, 20, 25, 30, 35, 40, 45, 50, 55, 60, 65, 70, 75, 80, 85, 90, 95, 100 ];
                FULLAD = 4095;
                k = (adValue / FULLAD);
                if (k < 1), resValue = 10000 * k / (1 - k);
                else
                    resValue = resTable(1);
                end
                if (resValue >= resTable(1)), tempM = -20;
                elseif (resValue <= resTable(25)), tempM = 100;
                else
                    for n = 1:24
                        if (resValue <= resTable(n) && resValue >= resTable(n + 1))
                            tempM = tempTable(n) + (resValue - resTable(n)) / (resTable(n + 1) - resTable(n)) * (tempTable(n + 1) - tempTable(n));
                            return;
                        end
                    end
                    tempM = 0;
                end
        end
        
        function TmrFcn(app, src, event, gui_fig)
            % --- Data Processing Timer ---
            
                if ~ishandle(gui_fig), return; end
                handles = guidata(gui_fig);
            
                if ~isstruct(handles) || ~isfield(handles, 'tcpsocket') || ...
                        isempty(handles.tcpsocket) || ~isobject(handles.tcpsocket) || ~isvalid(handles.tcpsocket)
                    return;
                end

                fprintf('>>> TMR TICK. NumBytesAvailable = %d\n', handles.tcpsocket.NumBytesAvailable);
            
                if (handles.tcpsocket.NumBytesAvailable > 0)
                    rawBytes = read(handles.tcpsocket, handles.tcpsocket.NumBytesAvailable, 'uint8');
                    rxBuf = [getappdata(gui_fig, 'rxBuffer'), char(rawBytes)];
                    fprintf('>>> RAW RX: %s\n', rxBuf);
            
                    lines = strsplit(rxBuf, {'\r\n', '\n', '\r'}, 'CollapseDelimiters', false);
                    setappdata(gui_fig, 'rxBuffer', lines{end});
            
                    for k = 1:(numel(lines) - 1)
                        getdata = lines{k};
                        if isempty(getdata), continue; end
            
                        % --- MM0 Processing (Front Motors / Driver I) ---
                        if contains(getdata, 'MM0')
                            strp = strrep(getdata,'MM0','');
                            strdata = strsplit(strtrim(strp), '=');
            
                            if strcmp(strdata{1},'A')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textLeftFrontCurrent, 'String', num2str(str2double(strsensor{1})/10, '%2.2f'));
                                set(handles.textRightFrontCurrent, 'String', num2str(str2double(strsensor{2})/10, '%2.2f'));
                            elseif strcmp(strdata{1},'AI')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textLeftFrontTemp, 'String', num2str(AD2Temperature(app, str2double(strsensor{3})), '%2.2f'));
                                set(handles.textRightFrontTemp, 'String', num2str(AD2Temperature(app, str2double(strsensor{4})), '%2.2f'));
                            elseif strcmp(strdata{1},'C')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textLeftFrontPos,'String',char(strsensor{1}));
                                set(handles.textRightFrontPos,'String',char(strsensor{2}));
            
                                odoState = getappdata(gui_fig, 'odoState');
                                if ~isempty(odoState)
                                    odoState.posLF = str2double(strsensor{1});
                                    odoState.posRF = str2double(strsensor{2});
                                    setappdata(gui_fig, 'odoState', odoState);
                                    processOdometry(app, gui_fig);
                                end
            
                                logData = getappdata(gui_fig, 'logData');
                                if ~isempty(logData)
                                    logData.currLF = str2double(strsensor{1});
                                    logData.currRF = str2double(strsensor{2});
                                    setappdata(gui_fig, 'logData', logData);
                                end
                            elseif strcmp(strdata{1},'P')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textLeftFrontPWM,'String',char(strsensor{1}));
                                set(handles.textRightFrontPWM,'String',char(strsensor{2}));
                            elseif strcmp(strdata{1},'S')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textLeftFrontVel,'String',char(strsensor{1}));
                                set(handles.textRightFrontVel,'String',char(strsensor{2}));
                            elseif strcmp(strdata{1},'V')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textBatteryVol, 'String', num2str(str2double(strsensor{2})/10, '%2.2f'));
                            elseif strcmp(strdata{1},'FF')
                                strMsg = 'Driver-I: ';
                                dataVal = str2double(strdata{2});
                                if (bitand(dataVal,1) > 0),    strMsg = strcat(strMsg,'OH '); end
                                if (bitand(dataVal,2) > 0),    strMsg = strcat(strMsg,'OV '); end
                                if (bitand(dataVal,4) > 0),    strMsg = strcat(strMsg,'UV '); end
                                if (bitand(dataVal,8) > 0),    strMsg = strcat(strMsg,'SHT '); end
                                if (bitand(dataVal,16) > 0),   strMsg = strcat(strMsg,'EStop '); end
                                if (dataVal == 0),             strMsg = strcat(strMsg,'OK'); end
                                set(handles.textDriver1State,'String',strMsg);
                            end
            
                        % --- MM1 Processing (Rear Motors / Driver II) ---
                        elseif contains(getdata, 'MM1')
                            strp = strrep(getdata,'MM1','' );
                            strdata = strsplit(strtrim(strp), '=');
            
                            if strcmp(strdata{1},'A')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textLeftRearCurrent, 'String', num2str(str2double(strsensor{1})/10, '%2.2f'));
                                set(handles.textRightRearCurrent, 'String', num2str(str2double(strsensor{2})/10, '%2.2f'));
                            elseif strcmp(strdata{1},'AI')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textLeftRearTemp, 'String', num2str(AD2Temperature(app, str2double(strsensor{3})), '%2.2f'));
                                set(handles.textRightRearTemp, 'String', num2str(AD2Temperature(app, str2double(strsensor{4})), '%2.2f'));
                            elseif strcmp(strdata{1},'C')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textLeftRearPos,'String',char(strsensor{1}));
                                set(handles.textRightRearPos,'String',char(strsensor{2}));
            
                                odoState = getappdata(gui_fig, 'odoState');
                                if ~isempty(odoState)
                                    odoState.posLR = str2double(strsensor{1});
                                    odoState.posRR = str2double(strsensor{2});
                                    setappdata(gui_fig, 'odoState', odoState);
                                    processOdometry(app, gui_fig);
                                end
            
                                logData = getappdata(gui_fig, 'logData');
                                if ~isempty(logData)
                                    logData.currLR = str2double(strsensor{1});
                                    logData.currRR = str2double(strsensor{2});
                                    setappdata(gui_fig, 'logData', logData);
                                end
                            elseif strcmp(strdata{1},'P')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textLeftRearPWM,'String',char(strsensor{1}));
                                set(handles.textRightRearPWM,'String',char(strsensor{2}));
                            elseif strcmp(strdata{1},'S')
                                strsensor = strsplit(char(strdata{2}),':');
                                set(handles.textLeftRearVel,'String',char(strsensor{1}));
                                set(handles.textRightRearVel,'String',char(strsensor{2}));
                            elseif strcmp(strdata{1},'FF')
                                strMsg = 'Driver-II: ';
                                dataVal = str2double(strdata{2});
                                if (bitand(dataVal,1) > 0),    strMsg = strcat(strMsg,'OH '); end
                                if (bitand(dataVal,2) > 0),    strMsg = strcat(strMsg,'OV '); end
                                if (bitand(dataVal,4) > 0),    strMsg = strcat(strMsg,'UV '); end
                                if (bitand(dataVal,8) > 0),    strMsg = strcat(strMsg,'SHT '); end
                                if (bitand(dataVal,16) > 0),   strMsg = strcat(strMsg,'EStop '); end
                                if (dataVal == 0),             strMsg = strcat(strMsg,'OK'); end
                                set(handles.textDriver2State,'String',strMsg);
                            end
            
                        % --- GPS Processing ---
                        elseif contains(getdata, '$GPRMC')
                            dataVals = strsplit(getdata, ',');
                            if (numel(dataVals) >= 10)
                                if strcmp(dataVals{3}, 'A')
                                    set(handles.textGPSState, 'String', 'Valid');
                                else
                                    set(handles.textGPSState, 'String', 'InValid');
                                end
            
                                if strcmp(dataVals{5}, 'S')
                                    tempLat = ['-', dataVals{4}];
                                else
                                    tempLat = ['+', dataVals{4}];
                                end
                                set(handles.textGPSLat, 'String', tempLat);
            
                                if strcmp(dataVals{7}, 'W')
                                    tempLong = ['-', dataVals{6}];
                                else
                                    tempLong = ['+', dataVals{6}];
                                end
                                set(handles.textGPSLong, 'String', tempLong);
            
                                set(handles.textGPSCOG, 'String', dataVals{9});
                                set(handles.textGPSVOG, 'String', dataVals{8});
                            end
            
                        % --- IMU Processing ---
                        elseif contains(getdata, '#')
                            dataVals = strsplit(getdata,',');
                            if (numel(dataVals) > 15)
                                set(handles.textSeq,'String',strtok(dataVals{1},'#'));
                                set(handles.textYaw,'String',dataVals{3});
                                set(handles.textGyroX,'String',dataVals{5});
                                set(handles.textGyroY,'String',dataVals{6});
                                set(handles.textGyroZ,'String',dataVals{7});
                                set(handles.textAccelX,'String',dataVals{9});
                                set(handles.textAccelY,'String',dataVals{10});
                                set(handles.textAccelZ,'String',dataVals{11});
                                set(handles.textCompX,'String',dataVals{13});
                                set(handles.textCompY,'String',dataVals{14});
                                set(handles.textCompZ,'String',dataVals{15});
            
                                odoState = getappdata(gui_fig, 'odoState');
                                if ~isempty(odoState)
                                    odoState.headingDeg = str2double(dataVals{3});
                                    setappdata(gui_fig, 'odoState', odoState);
                                end
            
                                logData = getappdata(gui_fig, 'logData');
                                if ~isempty(logData)
                                    logData.currIMU = [str2double(dataVals{3}), str2double(dataVals{5}), ...
                                                       str2double(dataVals{6}), str2double(dataVals{7}), ...
                                                       str2double(dataVals{9}), str2double(dataVals{10}), ...
                                                       str2double(dataVals{11}), str2double(dataVals{13}), ...
                                                       str2double(dataVals{14}), str2double(dataVals{15})];
                                    setappdata(gui_fig, 'logData', logData);
                                end
                            end
                        end
                    end
            
                    % Clean snapshot recorded per cycle
                    appendLogSnapshot(app, gui_fig, 'DATA_SAMPLE');
                end
            
                % === RE-ARM MOTOR WATCHDOG + REPEAT ACTIVE MOVEMENT COMMAND ===
                freshHandles = guidata(gui_fig);
                if isfield(freshHandles, 'tcpsocket') && ~isempty(freshHandles.tcpsocket)
                    sendCommand(app, freshHandles.tcpsocket, 'MMW !MG');
                end
                if isfield(freshHandles, 'currentMoveCmd') && ~isempty(freshHandles.currentMoveCmd)
                    sendCommand(app, freshHandles.tcpsocket, freshHandles.currentMoveCmd);
                end
        end
        
        function appendLogSnapshot(app, gui_fig, eventName)
                if nargin < 2, eventName = 'DATA_SAMPLE'; end
                logData = getappdata(gui_fig, 'logData');
                fprintf('>>> LOG NOW HAS %d SAMPLES\n', numel(logData.Timestamp));
                if isempty(logData), return; end
            
                logData.Timestamp{end+1, 1} = datestr(now, 'yyyy-mm-dd HH:MM:SS.FFF');
                logData.Enc_LF(end+1, 1)    = logData.currLF;
                logData.Enc_RF(end+1, 1)    = logData.currRF;
                logData.Enc_LR(end+1, 1)    = logData.currLR;
                logData.Enc_RR(end+1, 1)    = logData.currRR;
            
                logData.IMU_Yaw(end+1, 1)    = logData.currIMU(1);
                logData.IMU_GyroX(end+1, 1)  = logData.currIMU(2);
                logData.IMU_GyroY(end+1, 1)  = logData.currIMU(3);
                logData.IMU_GyroZ(end+1, 1)  = logData.currIMU(4);
                logData.IMU_AccelX(end+1, 1) = logData.currIMU(5);
                logData.IMU_AccelY(end+1, 1) = logData.currIMU(6);
                logData.IMU_AccelZ(end+1, 1) = logData.currIMU(7);
                logData.IMU_CompX(end+1, 1)  = logData.currIMU(8);
                logData.IMU_CompY(end+1, 1)  = logData.currIMU(9);
                logData.IMU_CompZ(end+1, 1)  = logData.currIMU(10);
            
                logData.Event{end+1, 1}     = eventName;
            
                setappdata(gui_fig, 'logData', logData);
        end
        
        function pwmVal = getPWMPower(app, handles)
            % --- Helper to extract motor PWM command value from GUI slider ---
            
                if ~isfield(handles, 'sliderPWMPower') || ~ishandle(handles.sliderPWMPower)
                    pwmVal = 150; % Safe slow default if slider handle missing
                    return;
                end
                rawVal = get(handles.sliderPWMPower, 'Value');
                maxVal = get(handles.sliderPWMPower, 'Max');
            
                MAX_SPEED_PWM = 100;
            
                if maxVal <= 1.0
                    pwmVal = int16(rawVal * MAX_SPEED_PWM);
                else
                    pwmVal = int16((rawVal / maxVal) * MAX_SPEED_PWM);
                end
            
                if pwmVal > 0 && pwmVal < 20
                    pwmVal = 20;
                end
        end
        
        function initDataLog(app, gui_fig)
            % --- Data Logging Helpers ---
            
                logData = struct();
                logData.Timestamp = {};
                logData.Enc_LF = [];
                logData.Enc_RF = [];
                logData.Enc_LR = [];
                logData.Enc_RR = [];
                logData.IMU_Yaw = [];
                logData.IMU_GyroX = [];
                logData.IMU_GyroY = [];
                logData.IMU_GyroZ = [];
                logData.IMU_AccelX = [];
                logData.IMU_AccelY = [];
                logData.IMU_AccelZ = [];
                logData.IMU_CompX = [];
                logData.IMU_CompY = [];
                logData.IMU_CompZ = [];
                logData.Event = {};
            
                % Snapshot state buffers
                logData.currLF = NaN;
                logData.currRF = NaN;
                logData.currLR = NaN;
                logData.currRR = NaN;
                logData.currIMU = nan(1, 10);
            
                setappdata(gui_fig, 'logData', logData);
        end
        
        function processOdometry(app, gui_fig)
            % --- Kinematics Calculation with Wheel Track & Body Specs ---
            
                odoState = getappdata(gui_fig, 'odoState');
                if isempty(odoState), return; end
            
                % Average left and right encoder channels
                currLeftAvg = (odoState.posLF + odoState.posLR) / 2.0;
                currRightAvg = ((odoState.posRF + odoState.posRR) / 2.0) * odoState.RIGHT_ENCODER_DIR;
            
                % Setup initial encoder reference
                if ~odoState.hasPrev
                    odoState.prevLeftAvg = currLeftAvg;
                    odoState.prevRightAvg = currRightAvg;
                    odoState.hasPrev = true;
                    setappdata(gui_fig, 'odoState', odoState);
                    return;
                end
            
                % Calculate encoder position increments
                dLeftTicks = currLeftAvg - odoState.prevLeftAvg;
                dRightTicks = currRightAvg - odoState.prevRightAvg;
            
                % Skip if robot hasn't moved
                if dLeftTicks == 0 && dRightTicks == 0
                    return;
                end
            
                % Distance traveled per side (meters)
                dL = dLeftTicks * odoState.METER_PER_COUNT;
                dR = dRightTicks * odoState.METER_PER_COUNT;
                dCenter = (dL + dR) / 2.0;
            
                % Track width angular displacement calculation
                dThetaEncoder = (dR - dL) / odoState.wheelTrack;
                odoState.encoderHeadingRad = odoState.encoderHeadingRad + dThetaEncoder;
            
                % Convert IMU degrees to radians
                headingRad = odoState.headingDeg * (pi / 180.0);
            
                % Position increments along global frame
                dx = dCenter * cos(headingRad);
                dy = dCenter * sin(headingRad);
            
                % Update global position
                odoState.x = odoState.x + dx;
                odoState.y = odoState.y + dy;
            
                % Store current ticks for next calculation step
                odoState.prevLeftAvg = currLeftAvg;
                odoState.prevRightAvg = currRightAvg;
            
                setappdata(gui_fig, 'odoState', odoState);
            
                % Console Output
                fprintf('X: %7.3f m | Y: %7.3f m | IMU Heading: %6.2f deg | Enc Heading: %6.2f deg\n', ...
                    odoState.x, odoState.y, odoState.headingDeg, odoState.encoderHeadingRad * (180/pi));
        end
        
        function saveDataToExcel(app, gui_fig)
                logData = getappdata(gui_fig, 'logData');
                if isempty(logData) || isempty(logData.Timestamp)
                    disp('No telemetry data available to export.');
                    drawnow;
                    msgbox('No telemetry data has been recorded yet, so there is nothing to export.', ...
                        'Telemetry Export', 'warn');
                    return;
                end
            
                try
                    logTable = table(logData.Timestamp, logData.Enc_LF, logData.Enc_RF, ...
                        logData.Enc_LR, logData.Enc_RR, logData.IMU_Yaw, logData.IMU_GyroX, ...
                        logData.IMU_GyroY, logData.IMU_GyroZ, logData.IMU_AccelX, logData.IMU_AccelY, ...
                        logData.IMU_AccelZ, logData.IMU_CompX, logData.IMU_CompY, logData.IMU_CompZ, ...
                        logData.Event, 'VariableNames', { ...
                        'Timestamp', 'Enc_LeftFront', 'Enc_RightFront', 'Enc_LeftRear', 'Enc_RightRear', ...
                        'IMU_Yaw', 'IMU_GyroX', 'IMU_GyroY', 'IMU_GyroZ', 'IMU_AccelX', ...
                        'IMU_AccelY', 'IMU_AccelZ', 'IMU_CompX', 'IMU_CompY', 'IMU_CompZ', 'Event'});
            
                    % --- FIX: save next to this .m file instead of relying on pwd,
                    % which can silently point somewhere other than where you expect
                    % depending on how/where the GUI was launched.
                    outDir = fileparts(mfilename('fullpath'));
                    if isempty(outDir)
                        outDir = pwd;
                    end
            
                    filename = sprintf('RobotDataLog_%s.xlsx', datestr(now, 'yyyymmdd_HHMMSS'));
                    fullFilePath = fullfile(outDir, filename);
            
                    writetable(logTable, fullFilePath, 'Sheet', 'SensorData');
            
                    % --- FIX: verify the file really landed on disk before declaring
                    % success, and force the Command Window to flush the message now
                    % (instead of it possibly getting buffered/delayed).
                    if exist(fullFilePath, 'file') == 2
                        fprintf('\n========================================\n');
                        fprintf('SUCCESS: %d records exported to Excel.\n', height(logTable));
                        fprintf('FILE PATH: %s\n', fullFilePath);
                        fprintf('========================================\n\n');
                        drawnow;
                        msgbox(sprintf('Saved %d records to:\n%s', height(logTable), fullFilePath), ...
                            'Telemetry Export');
                    else
                        warning('writetable() did not error, but no file was found at:\n%s', fullFilePath);
                    end
                catch ME
                    warning(['Failed to export Excel log: ' ME.message]);
                    drawnow;
                end
        end
        
        function sendCommand(app, socket, cmdStr)
            % --- Helper to handle tcpclient writing ---
            
                if ~isempty(socket) && isvalid(socket)
                    write(socket, uint8([cmdStr char(13) char(10)]));
                end
        end
        
        function setMoveCommand(app, hObject, handles, cmd)
            % --- Helper to update and broadcast movement command across GUI threads ---
            
                fig = ancestor(hObject, 'figure');
                freshHandles = guidata(fig);
            
                freshHandles.currentMoveCmd = cmd;
                guidata(fig, freshHandles);
            
                if isfield(freshHandles, 'tcpsocket') && ~isempty(freshHandles.tcpsocket)
                    sendCommand(app, freshHandles.tcpsocket, cmd);
                end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DrRobotMatlabSDK_OpeningFcn(app, varargin)
            % --- Executes just before DrRobotMatlabSDK is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                handles.output = hObject;
                handles.delay = 0;
                handles.cnt = 0;
                handles.connected = 0;
                handles.currentMoveCmd = 'MMW !M 0 0'; % Safe default start state
            
                % Store handle to main figure
                handles.guifig = ancestor(hObject, 'figure');
            
                handles.tmr = timer('TimerFcn', {@app.TmrFcn, handles.guifig}, 'BusyMode', 'Queue', ...
                    'ExecutionMode', 'FixedRate', 'Period', 0.05);
            
                handles.tcpsocket = [];
            
                % Initialize TCP Rx buffer
                setappdata(handles.guifig, 'rxBuffer', '');
            
                % === DATA LOGGING INITIALIZATION ===
                initDataLog(app, handles.guifig);
            
                % === ODOMETRY LOCALIZATION INITIALIZATION ===
                odoState = struct();
                odoState.x = 0.0;                 % World Position X (meters)
                odoState.y = 0.0;                 % World Position Y (meters)
                odoState.headingDeg = 0.0;        % IMU Heading (degrees)
                odoState.encoderHeadingRad = 0.0; % Pure encoder heading fallback (radians)
            
                odoState.posLF = 0;               % Left Front encoder count
                odoState.posRF = 0;               % Right Front encoder count
                odoState.posLR = 0;               % Left Rear encoder count
                odoState.posRR = 0;               % Right Rear encoder count
            
                odoState.prevLeftAvg = 0;
                odoState.prevRightAvg = 0;
                odoState.hasPrev = false;
            
                % Physical Specifications
                wheelRadius = 0.13;               % 13 cm radius
                encoderCPR = 300;                 % 300 counts per revolution
            
                odoState.METER_PER_COUNT = (2 * pi * wheelRadius) / encoderCPR; % 0.0027227 m/tick
                odoState.wheelTrack = 0.47;       % 47 cm wheel-to-wheel distance
                odoState.bodyLength = 0.56;       % 56 cm body length
            
                odoState.RIGHT_ENCODER_DIR = -1;  % Jaguar right motor polarity compensation
            
                setappdata(handles.guifig, 'odoState', odoState);
            
                guidata(handles.guifig, handles);
                guidata(hObject, handles);
        end

        % Button pushed function: EStop
        function EStop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                fig = ancestor(hObject, 'figure');
                handles = guidata(fig);
                judge = strcmp(get(hObject, 'String'), 'EStop');
                if (judge == 1)
                    set(hObject,'String','Release EStop');
                    sendCommand(app, handles.tcpsocket, 'MMW !EX');
                    setMoveCommand(app, hObject, handles, 'MMW !EX');
                    appendLogSnapshot(app, fig, 'ESTOP_ENGAGED');
                else
                    set(hObject,'String','EStop');
                    sendCommand(app, handles.tcpsocket, 'MMW !MG');
                    setMoveCommand(app, hObject, handles, 'MMW !M 0 0');
                    appendLogSnapshot(app, fig, 'ESTOP_RELEASED');
                end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                try
                    if isfield(handles, 'tcpsocket') && ~isempty(handles.tcpsocket) && isvalid(handles.tcpsocket)
                        delete(handles.tcpsocket);
                    end
                catch ME
                    warning(['Error closing socket: ' ME.message]);
                end
            
                try
                    if isfield(handles, 'tmr') && isvalid(handles.tmr)
                        stop(handles.tmr);
                        delete(handles.tmr);
                    end
                catch ME
                    warning(['Error stopping timer: ' ME.message]);
                end
            
                % Resume MATLAB execution before releasing the figure
                if ishandle(hObject)
                    uiresume(hObject);
                end
        end

        % Button pushed function: pushbuttonConnect
        function pushbuttonConnect_Callback(app, event)
            % --- Connection Management ---
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                fig = ancestor(hObject, 'figure');
                handles = guidata(fig);
                judge = strcmp(get(hObject, 'String'), 'Connect');
            
                if (judge == 1)
                    ip = get(handles.editRobotIP, 'String');
                    port = str2double(get(handles.editRobotPort, 'String'));
                    try
                        handles.tcpsocket = tcpclient(ip, port, 'Timeout', 5);
                        handles.connected = 1;
                        handles.cnt = 0;
                        handles.delay = 0;
                        handles.currentMoveCmd = 'MMW !M 0 0';
                        setappdata(fig, 'rxBuffer', '');
            
                        % Prepare data log structure
                        initDataLog(app, fig);
            
                        sendCommand(app, handles.tcpsocket, 'MMW !MG');
            
                        set(hObject, 'String', 'DisConnect');
                        guidata(fig, handles);
            
                        start(handles.tmr);
                    catch ME
                        errordlg(['Connection Error: ' ME.message]);
                    end
                else
                    % --- FIX: cleanup steps are now isolated in their own try/catch
                    % blocks. Previously, if stop(handles.tmr) or delete(handles.tcpsocket)
                    % threw an error (e.g. the socket was already invalid), the callback
                    % aborted right there and saveDataToExcel() below was NEVER reached --
                    % which is why nothing got saved or printed.
                    try
                        if isfield(handles, 'tmr') && isvalid(handles.tmr)
                            stop(handles.tmr);
                        end
                    catch ME
                        warning(['Error stopping timer: ' ME.message]);
                    end
            
                    try
                        if isfield(handles, 'tcpsocket') && ~isempty(handles.tcpsocket) && isvalid(handles.tcpsocket)
                            delete(handles.tcpsocket);
                        end
                    catch ME
                        warning(['Error closing socket: ' ME.message]);
                    end
                    handles.tcpsocket = [];
            
                    handles.connected = 0;
                    set(hObject, 'String', 'Connect');
                    guidata(fig, handles);
                end
        end

        % Button pushed function: pushbuttonGoBack
        function pushbuttonGoBack_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                handles = guidata(ancestor(hObject, 'figure'));
                value = getPWMPower(app, handles);
                cmd = sprintf('MMW !M %d %d', -value, value);
                setMoveCommand(app, hObject, handles, cmd);
        end

        % Button pushed function: pushbuttonGoF
        function pushbuttonGoF_Callback(app, event)
            % --- Movement Pushbutton Callbacks ---
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                handles = guidata(ancestor(hObject, 'figure'));
                value = getPWMPower(app, handles);
                cmd = sprintf('MMW !M %d %d', value, -value);
                setMoveCommand(app, hObject, handles, cmd);
        end

        % Button pushed function: pushbuttonOff
        function pushbuttonOff_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                fig = ancestor(hObject, 'figure');
                handles = guidata(fig);
                sendCommand(app, handles.tcpsocket, 'SYS MMC 127');
            
                % Export logged telemetry to Excel whenever the lights are switched off.
                saveDataToExcel(app, fig);
        end

        % Button pushed function: pushbuttonOn
        function pushbuttonOn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                handles = guidata(ancestor(hObject, 'figure'));
                sendCommand(app, handles.tcpsocket, 'SYS MMC 255');
        end

        % Button pushed function: pushbuttonSensorCal
        function pushbuttonSensorCal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                handles = guidata(ancestor(hObject, 'figure'));
                sendCommand(app, handles.tcpsocket, 'SYS CAL');
        end

        % Button pushed function: pushbuttonStop
        function pushbuttonStop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                handles = guidata(ancestor(hObject, 'figure'));
                cmd = 'MMW !M 0 0';
                setMoveCommand(app, hObject, handles, cmd);
                appendLogSnapshot(app, ancestor(hObject, 'figure'), 'STOP_BUTTON_PRESSED');
        end

        % Button pushed function: pushbuttonTurnLeft
        function pushbuttonTurnLeft_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                handles = guidata(ancestor(hObject, 'figure'));
                value = getPWMPower(app, handles);
                cmd = sprintf('MMW !M %d %d', -value, -value);
                setMoveCommand(app, hObject, handles, cmd);
        end

        % Button pushed function: pushbuttonTurnRight
        function pushbuttonTurnRight_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                handles = guidata(ancestor(hObject, 'figure'));
                value = getPWMPower(app, handles);
                cmd = sprintf('MMW !M %d %d', value, value);
                setMoveCommand(app, hObject, handles, cmd);
        end

        % Button pushed function: pushbuttonSimulate
        function pushbuttonSimulateButtonPushed(app, event)
            fig = app.figure1;
            fprintf('>>> TEST MODE: generating simulated telemetry (no robot needed)...\n');

            % Start each test run with a clean log
            initDataLog(app, fig);

            odoState = getappdata(fig, 'odoState');
            if isempty(odoState)
                odoState = struct('x',0,'y',0,'headingDeg',0,'encoderHeadingRad',0, ...
                    'posLF',0,'posRF',0,'posLR',0,'posRR',0, ...
                    'prevLeftAvg',0,'prevRightAvg',0,'hasPrev',false, ...
                    'METER_PER_COUNT',(2*pi*0.13)/300,'wheelTrack',0.47,'bodyLength',0.56, ...
                    'RIGHT_ENCODER_DIR',-1);
            end
            odoState.x = 0; odoState.y = 0; odoState.headingDeg = 0;
            odoState.encoderHeadingRad = 0; odoState.hasPrev = false;
            setappdata(fig, 'odoState', odoState);

            encLF = 0; encRF = 0; encLR = 0; encRR = 0;
            yaw = 0;

            for i = 1:20
                % Pretend the robot is slowly driving forward while turning a bit
                encLF = encLF + 50; encRF = encRF + 50;
                encLR = encLR + 50; encRR = encRR + 50;
                yaw = mod(yaw + 3, 360);

                odoState = getappdata(fig, 'odoState');
                odoState.posLF = encLF; odoState.posRF = encRF;
                odoState.posLR = encLR; odoState.posRR = encRR;
                odoState.headingDeg = yaw;
                setappdata(fig, 'odoState', odoState);
                processOdometry(app, fig);

                logData = getappdata(fig, 'logData');
                logData.currLF = encLF; logData.currRF = encRF;
                logData.currLR = encLR; logData.currRR = encRR;
                logData.currIMU = [yaw, 0.01, 0.02, 0.03, 0.10, 0.20, 0.98, 15.0, -3.0, 42.0];
                setappdata(fig, 'logData', logData);

                appendLogSnapshot(app, fig, 'SIMULATED_SAMPLE');
                drawnow;
                pause(0.02);
            end

            fprintf('>>> TEST MODE: done generating data, saving to Excel...\n');
            saveDataToExcel(app, fig);
        end

        % Key press function: figure1
        function figure1KeyPress(app, event)
            handles = guidata(app.figure1);
            value = getPWMPower(app, handles);

            switch event.Key
                case 'uparrow'
                    cmd = sprintf('MMW !M %d %d', value, -value);
                    setMoveCommand(app, app.figure1, handles, cmd);
                case 'downarrow'
                    cmd = sprintf('MMW !M %d %d', -value, value);
                    setMoveCommand(app, app.figure1, handles, cmd);
                case 'leftarrow'
                    cmd = sprintf('MMW !M %d %d', -value, -value);
                    setMoveCommand(app, app.figure1, handles, cmd);
                case 'rightarrow'
                    cmd = sprintf('MMW !M %d %d', value, value);
                    setMoveCommand(app, app.figure1, handles, cmd);
                case 'space'
                    cmd = 'MMW !M 0 0';
                    setMoveCommand(app, app.figure1, handles, cmd);
                    appendLogSnapshot(app, app.figure1, 'STOP_KEY_PRESSED');
            end
        end

        % Key release function: figure1
        function figure1KeyRelease(app, event)
            if any(strcmp(event.Key, {'uparrow','downarrow','leftarrow','rightarrow'}))
                handles = guidata(app.figure1);
                cmd = 'MMW !M 0 0';
                setMoveCommand(app, app.figure1, handles, cmd);
            end
            
        end

        % Callback function
        function get(app, event)
            changingValue = event.Value;
            
        end

        % Callback function
        function g(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DrRobotMatlabSDK';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1KeyPress, true);
            app.figure1.KeyReleaseFcn = createCallbackFcn(app, @figure1KeyRelease, true);
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Robot Connection';
            app.uipanel1.Tag = 'uipanel1';
            app.pushbuttonConnect = uibutton(app.uipanel1, 'push');
            app.pushbuttonConnect.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonConnect_Callback, true);
            app.pushbuttonConnect.Tag = 'pushbuttonConnect';
            app.pushbuttonConnect.Text = 'Connect';
            app.editRobotIP = uieditfield(app.uipanel1, 'text');
            app.editRobotIP.Tag = 'editRobotIP';
            app.editRobotIP.Value = '192.168.1.60';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Robot IP:';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Robot Port:';
            app.editRobotPort = uieditfield(app.uipanel1, 'text');
            app.editRobotPort.Tag = 'editRobotPort';
            app.editRobotPort.Value = '10001';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Robot Sensor:';
            app.uipanel2.Tag = 'uipanel2';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Left Front Motor::';
            app.textLeftFrontPWM = uilabel(app.uipanel2);
            app.textLeftFrontPWM.Tag = 'textLeftFrontPWM';
            app.textLeftFrontPWM.Text = '0';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Right Front Motor:';
            app.textRightFrontPWM = uilabel(app.uipanel2);
            app.textRightFrontPWM.Tag = 'textRightFrontPWM';
            app.textRightFrontPWM.Text = '0';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Right Rear Motor:';
            app.textLeftFrontPos = uilabel(app.uipanel2);
            app.textLeftFrontPos.Tag = 'textLeftFrontPos';
            app.textLeftFrontPos.Text = '0';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Left Rear Motor:';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = 'PWM';
            app.textLeftFrontVel = uilabel(app.uipanel2);
            app.textLeftFrontVel.Tag = 'textLeftFrontVel';
            app.textLeftFrontVel.Text = '0';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Position';
            app.textRightFrontVel = uilabel(app.uipanel2);
            app.textRightFrontVel.Tag = 'textRightFrontVel';
            app.textRightFrontVel.Text = '0';
            app.text16 = uilabel(app.uipanel2);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Velocity';
            app.text17 = uilabel(app.uipanel2);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Temperature';
            app.textLeftFrontTemp = uilabel(app.uipanel2);
            app.textLeftFrontTemp.Tag = 'textLeftFrontTemp';
            app.textLeftFrontTemp.Text = '0';
            app.textLeftRearPWM = uilabel(app.uipanel2);
            app.textLeftRearPWM.Tag = 'textLeftRearPWM';
            app.textLeftRearPWM.Text = '0';
            app.textRightRearPWM = uilabel(app.uipanel2);
            app.textRightRearPWM.Tag = 'textRightRearPWM';
            app.textRightRearPWM.Text = '0';
            app.textLeftRearPos = uilabel(app.uipanel2);
            app.textLeftRearPos.Tag = 'textLeftRearPos';
            app.textLeftRearPos.Text = '0';
            app.textRightRearPos = uilabel(app.uipanel2);
            app.textRightRearPos.Tag = 'textRightRearPos';
            app.textRightRearPos.Text = '0';
            app.textLeftRearVel = uilabel(app.uipanel2);
            app.textLeftRearVel.Tag = 'textLeftRearVel';
            app.textLeftRearVel.Text = '0';
            app.textRightRearVel = uilabel(app.uipanel2);
            app.textRightRearVel.Tag = 'textRightRearVel';
            app.textRightRearVel.Text = '0';
            app.textRightFrontTemp = uilabel(app.uipanel2);
            app.textRightFrontTemp.Tag = 'textRightFrontTemp';
            app.textRightFrontTemp.Text = '0';
            app.textLeftRearTemp = uilabel(app.uipanel2);
            app.textLeftRearTemp.Tag = 'textLeftRearTemp';
            app.textLeftRearTemp.Text = '0';
            app.textRightRearTemp = uilabel(app.uipanel2);
            app.textRightRearTemp.Tag = 'textRightRearTemp';
            app.textRightRearTemp.Text = '0';
            app.text28 = uilabel(app.uipanel2);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Current';
            app.textLeftFrontCurrent = uilabel(app.uipanel2);
            app.textLeftFrontCurrent.Tag = 'textLeftFrontCurrent';
            app.textLeftFrontCurrent.Text = '0';
            app.textRightFrontCurrent = uilabel(app.uipanel2);
            app.textRightFrontCurrent.Tag = 'textRightFrontCurrent';
            app.textRightFrontCurrent.Text = '0';
            app.textLeftRearCurrent = uilabel(app.uipanel2);
            app.textLeftRearCurrent.Tag = 'textLeftRearCurrent';
            app.textLeftRearCurrent.Text = '0';
            app.textRightRearCurrent = uilabel(app.uipanel2);
            app.textRightRearCurrent.Tag = 'textRightRearCurrent';
            app.textRightRearCurrent.Text = '0';
            app.textRightFrontPos = uilabel(app.uipanel2);
            app.textRightFrontPos.Tag = 'textRightFrontPos';
            app.textRightFrontPos.Text = '0';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Motion Control';
            app.uipanel3.Tag = 'uipanel3';
            app.pushbuttonGoF = uibutton(app.uipanel3, 'push');
            app.pushbuttonGoF.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonGoF_Callback, true);
            app.pushbuttonGoF.Tag = 'pushbuttonGoF';
            app.pushbuttonGoF.Text = '^';
            app.pushbuttonStop = uibutton(app.uipanel3, 'push');
            app.pushbuttonStop.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonStop_Callback, true);
            app.pushbuttonStop.Tag = 'pushbuttonStop';
            app.pushbuttonStop.Text = 'O';
            app.pushbuttonTurnLeft = uibutton(app.uipanel3, 'push');
            app.pushbuttonTurnLeft.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonTurnLeft_Callback, true);
            app.pushbuttonTurnLeft.Tag = 'pushbuttonTurnLeft';
            app.pushbuttonTurnLeft.Text = '<';
            app.pushbuttonTurnRight = uibutton(app.uipanel3, 'push');
            app.pushbuttonTurnRight.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonTurnRight_Callback, true);
            app.pushbuttonTurnRight.Tag = 'pushbuttonTurnRight';
            app.pushbuttonTurnRight.Text = '>';
            app.pushbuttonGoBack = uibutton(app.uipanel3, 'push');
            app.pushbuttonGoBack.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonGoBack_Callback, true);
            app.pushbuttonGoBack.Tag = 'pushbuttonGoBack';
            app.pushbuttonGoBack.Text = 'v';
            app.sliderPWMPower = uislider(app.uipanel3);
            app.sliderPWMPower.Limits = [50 500];
            app.sliderPWMPower.MajorTicks = [];
            app.sliderPWMPower.Step = 0.01;
            app.sliderPWMPower.MinorTicksMode = 'manual';
            app.sliderPWMPower.Tag = 'sliderPWMPower';
            app.sliderPWMPower.Value = 200;
            app.EStop = uibutton(app.uipanel3, 'push');
            app.EStop.ButtonPushedFcn = createCallbackFcn(app, @EStop_Callback, true);
            app.EStop.Tag = 'EStop';
            app.EStop.Text = 'Release EStop';
            app.pushbuttonOn = uibutton(app.uipanel3, 'push');
            app.pushbuttonOn.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonOn_Callback, true);
            app.pushbuttonOn.Tag = 'pushbuttonOn';
            app.pushbuttonOn.Text = 'Light On';
            app.pushbuttonOff = uibutton(app.uipanel3, 'push');
            app.pushbuttonOff.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonOff_Callback, true);
            app.pushbuttonOff.Tag = 'pushbuttonOff';
            app.pushbuttonOff.Text = 'Light Off';
            app.pushbuttonSensorCal = uibutton(app.uipanel3, 'push');
            app.pushbuttonSensorCal.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSensorCal_Callback, true);
            app.pushbuttonSensorCal.Tag = 'pushbuttonSensorCal';
            app.pushbuttonSensorCal.Text = 'Sensor Cal';
            app.pushbuttonSimulate = uibutton(app.uipanel3, 'push');
            app.pushbuttonSimulate.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSimulateButtonPushed, true);
            app.pushbuttonSimulate.Text = 'Tester Btn';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Panel';
            app.uipanel4.Tag = 'uipanel4';
            app.text33 = uilabel(app.uipanel4);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Driver-I State:';
            app.textDriver1State = uilabel(app.uipanel4);
            app.textDriver1State.Tag = 'textDriver1State';
            app.textDriver1State.Text = '';
            app.text35 = uilabel(app.uipanel4);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Driver-I State:';
            app.textDriver2State = uilabel(app.uipanel4);
            app.textDriver2State.Tag = 'textDriver2State';
            app.textDriver2State.Text = '';
            app.textBatteryVol = uilabel(app.uipanel4);
            app.textBatteryVol.Tag = 'textBatteryVol';
            app.textBatteryVol.Text = '0';
            app.text38 = uilabel(app.uipanel4);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Battery Vol:';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'IMU&GPS';
            app.uipanel5.Tag = 'uipanel5';
            app.text39 = uilabel(app.uipanel5);
            app.text39.Tag = 'text39';
            app.text39.Text = 'Accel:';
            app.text40 = uilabel(app.uipanel5);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Gyro:';
            app.text41 = uilabel(app.uipanel5);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Compass:';
            app.text42 = uilabel(app.uipanel5);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Latitude:';
            app.text43 = uilabel(app.uipanel5);
            app.text43.Tag = 'text43';
            app.text43.Text = 'Longitude:';
            app.text44 = uilabel(app.uipanel5);
            app.text44.Tag = 'text44';
            app.text44.Text = 'COG:';
            app.text45 = uilabel(app.uipanel5);
            app.text45.Tag = 'text45';
            app.text45.Text = 'VOG:';
            app.text46 = uilabel(app.uipanel5);
            app.text46.Tag = 'text46';
            app.text46.Text = 'X';
            app.text47 = uilabel(app.uipanel5);
            app.text47.Tag = 'text47';
            app.text47.Text = 'Y';
            app.text48 = uilabel(app.uipanel5);
            app.text48.Tag = 'text48';
            app.text48.Text = 'Z';
            app.text49 = uilabel(app.uipanel5);
            app.text49.Tag = 'text49';
            app.text49.Text = 'GPS State:';
            app.textAccelX = uilabel(app.uipanel5);
            app.textAccelX.Tag = 'textAccelX';
            app.textAccelX.Text = '0';
            app.textAccelY = uilabel(app.uipanel5);
            app.textAccelY.Tag = 'textAccelY';
            app.textAccelY.Text = '0';
            app.textAccelZ = uilabel(app.uipanel5);
            app.textAccelZ.Tag = 'textAccelZ';
            app.textAccelZ.Text = '0';
            app.textGyroX = uilabel(app.uipanel5);
            app.textGyroX.Tag = 'textGyroX';
            app.textGyroX.Text = '0';
            app.textGyroY = uilabel(app.uipanel5);
            app.textGyroY.Tag = 'textGyroY';
            app.textGyroY.Text = '0';
            app.textGyroZ = uilabel(app.uipanel5);
            app.textGyroZ.Tag = 'textGyroZ';
            app.textGyroZ.Text = '0';
            app.textCompX = uilabel(app.uipanel5);
            app.textCompX.Tag = 'textCompX';
            app.textCompX.Text = '0';
            app.textCompY = uilabel(app.uipanel5);
            app.textCompY.Tag = 'textCompY';
            app.textCompY.Text = '0';
            app.textCompZ = uilabel(app.uipanel5);
            app.textCompZ.Tag = 'textCompZ';
            app.textCompZ.Text = '0';
            app.textGPSLat = uilabel(app.uipanel5);
            app.textGPSLat.Tag = 'textGPSLat';
            app.textGPSLat.Text = '0';
            app.textGPSLong = uilabel(app.uipanel5);
            app.textGPSLong.Tag = 'textGPSLong';
            app.textGPSLong.Text = '0';
            app.textGPSCOG = uilabel(app.uipanel5);
            app.textGPSCOG.Tag = 'textGPSCOG';
            app.textGPSCOG.Text = '0';
            app.textGPSVOG = uilabel(app.uipanel5);
            app.textGPSVOG.Tag = 'textGPSVOG';
            app.textGPSVOG.Text = '0';
            app.textGPSState = uilabel(app.uipanel5);
            app.textGPSState.Tag = 'textGPSState';
            app.textGPSState.Text = '0';
            app.text64 = uilabel(app.uipanel5);
            app.text64.Tag = 'text64';
            app.text64.Text = 'Yaw:';
            app.textYaw = uilabel(app.uipanel5);
            app.textYaw.Tag = 'textYaw';
            app.textYaw.Text = '0';
            app.text66 = uilabel(app.uipanel5);
            app.text66.Tag = 'text66';
            app.text66.Text = 'Seq:';
            app.textSeq = uilabel(app.uipanel5);
            app.textSeq.Tag = 'textSeq';
            app.textSeq.Text = '0';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DrRobotsMatlabSDKOdmetryRecord_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)DrRobotMatlabSDK_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end